classdef FAapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FAUIFigure                    matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        Label_2                       matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        Label                         matlab.ui.control.Label
        LiczbaepokEditFieldLabel      matlab.ui.control.Label
        LiczbaepokEditField           matlab.ui.control.NumericEditField
        LiczbawietlikwEditFieldLabel  matlab.ui.control.Label
        LiczbaSwietlikowEditField     matlab.ui.control.NumericEditField
        AlfalosowoEditFieldLabel      matlab.ui.control.Label
        AlfaEditField                 matlab.ui.control.NumericEditField
        ZbirwktrymposzukiwanejestmaksimumEditFieldLabel  matlab.ui.control.Label
        ZakresEditField               matlab.ui.control.EditField
        ParametryLabel                matlab.ui.control.Label
        GammazdolnoabsorpcyjnaEditFieldLabel  matlab.ui.control.Label
        GammaEditField                matlab.ui.control.NumericEditField
        DeltaredukcjaalfaEditFieldLabel  matlab.ui.control.Label
        DeltaEditField                matlab.ui.control.NumericEditField
        UruchomButton                 matlab.ui.control.Button
        xLabel                        matlab.ui.control.Label
        xEditField                    matlab.ui.control.NumericEditField
        yLabel                        matlab.ui.control.Label
        yEditField                    matlab.ui.control.NumericEditField
        zLabel                        matlab.ui.control.Label
        zEditField                    matlab.ui.control.NumericEditField
        ZnalezionemaksimumlokalnefunkcjiLabel  matlab.ui.control.Label
        Wzrfunkcji2zmiennychLabel     matlab.ui.control.Label
        WzorDropDown                  matlab.ui.control.DropDown
        dlapooeniaxyLabel             matlab.ui.control.Label
        iLabel                        matlab.ui.control.Label
        gdzieLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        
    end
    
    methods (Access = public)
        
        function [xSwietlikow, ySwietlikow] = ruchSwietlikow(app, xSwietlikow, ySwietlikow, jasnoscSwietlikow, alfa, gamma, graniceX, graniceY)
            rozmiarX = size(xSwietlikow, 2);
            %poruszenie najjasniejszego swietlika
            xSwietlikow(rozmiarX) = xSwietlikow(rozmiarX) + alfa .* (rand-0.5);
            ySwietlikow(rozmiarX) = ySwietlikow(rozmiarX) + alfa .* (rand-0.5);
            for i = 1 : rozmiarX
                for j = 1 : rozmiarX
                    %odlegosc miedzy swietlikami
                    xSwietlikow(1) = xSwietlikow(1) + alfa .* (rand-0.5);
                    ySwietlikow(1) = ySwietlikow(1) + alfa .* (rand-0.5);
                    r = sqrt((xSwietlikow(i)-xSwietlikow(j))^2+(ySwietlikow(i)-ySwietlikow(j))^2);
                    if jasnoscSwietlikow(i) < jasnoscSwietlikow(j)
                        %przesuniecie swietlika
                        beta = exp(-gamma*r.^2);
                        %xSwietlikow(i) = xSwietlikow(i) .* (1-beta) + xSwietlikow(j) .* beta+alfa .* (rand-0.5);
                        %ySwietlikow(i) = ySwietlikow(i) .* (1-beta) + ySwietlikow(j) .* beta+alfa .* (rand-0.5);
                        xSwietlikow(i) = xSwietlikow(i) + beta .* (xSwietlikow(j) - xSwietlikow(i)) + alfa .* (rand-0.5);
                        ySwietlikow(i) = ySwietlikow(i) + beta .* (ySwietlikow(j) - ySwietlikow(i)) + alfa .* (rand-0.5);
                    end
                end
            end
            %ograniczenie ruchu swietlikow w granicach funkcji
            for i = 1:length(xSwietlikow)
                if xSwietlikow(i) <= graniceX(1)
                    xSwietlikow(i) = graniceX(1);
                end
                if xSwietlikow(i) >= graniceX(2)
                    xSwietlikow(i) = graniceX(2);
                end
                if ySwietlikow(i) <= graniceY(1)
                    ySwietlikow(i) = graniceY(1);
                end
                if ySwietlikow(i) >= graniceY(2)
                    ySwietlikow(i) = graniceY(2);
                end
            end
        end
        
        function [wzor, graniceX, graniceY] = wzorFunc(app, wzorValue)
            if wzorValue == "f(x, y) = sin(x/2) + cos(y/2)"
                wzor = @(x,y) sin(x/2)+cos(y/2);
                graniceX = [-5 5];
                graniceY = [-5 5];
            elseif wzorValue == "f(x, y) = -sqrt(x.^2+y.^2)"
                wzor = @(x,y) -sqrt(x.^2+y.^2);
                graniceX = [-5 5];
                graniceY = [-5 5];
            elseif wzorValue == "f(x, y) = x.*exp(-1.*(x.^2+y.^2))"
                wzor = @(x,y) x.*exp(-1.*(x.^2+y.^2));
                graniceX = [-2 2];
                graniceY = [-2 2];
            elseif wzorValue == "f(x, y) = 1-abs(x+y)-abs(y-x)"
                wzor = @(x,y) 1-abs(x+y)-abs(y-x);
                graniceX = [-5 5];
                graniceY = [-5 5];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Close request function: FAUIFigure
        function FAUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: UruchomButton
        function UruchomButtonPushed(app, event)
            liczbaSwietlikow = app.LiczbaSwietlikowEditField.Value;
            liczbaEpok = app.LiczbaepokEditField.Value;
            [f, graniceX, graniceY] = wzorFunc(app, app.WzorDropDown.Value);
            alfa = app.AlfaEditField.Value;
            gamma = app.GammaEditField.Value;
            delta = app.DeltaEditField.Value;
            zakresX = graniceX(2) - graniceX(1);
            zakresY = graniceY(2) - graniceY(1);
            [x, y] = meshgrid(graniceX(1):0.1:graniceX(2), graniceY(1):0.1:graniceY(2));
            z = f(x, y);
            xSwietlikow = graniceX(1) + zakresX * rand(1, liczbaSwietlikow);
            ySwietlikow = graniceY(1) + zakresY * rand(1, liczbaSwietlikow);
            jasnoscSwietlikow = zeros(size(ySwietlikow));
            if strcmpi(app.Switch.Value,'Funkcja 3D')
                surfc(app.UIAxes, x, y, z);
            end
            if strcmpi(app.Switch.Value, 'Świetliki')
                app.UIAxes.View = [0 90];
                try
                    for i=1:liczbaEpok
                        %rysowanie konturow funkcji
                        contour(app.UIAxes, x, y, z, 15);
                        hold(app.UIAxes, "on");
                        %rysowanie swietlikow
                        plot(app.UIAxes, xSwietlikow, ySwietlikow, 'o', 'markersize', 4, 'MarkerFaceColor', 'k' ,'MarkerEdgeColor', 'k')
                        drawnow;
                        hold(app.UIAxes, "off");
                        %segregowanie swietlikow ze wzgledu na ich wartosc z (czemu?)
                        zSwietlikow = f(xSwietlikow, ySwietlikow);
                        [jasnoscSwietlikow, kolejnoscSwietlikow] = sort(zSwietlikow);
                        xSwietlikow = xSwietlikow(kolejnoscSwietlikow);
                        ySwietlikow = ySwietlikow(kolejnoscSwietlikow);
                        %wykonanie ruchu swietlikow
                        [xSwietlikow, ySwietlikow] = ruchSwietlikow(app, xSwietlikow, ySwietlikow, jasnoscSwietlikow, alfa, gamma, graniceX, graniceY);
                        %zmiana alfy (?)
                        alfa = alfa * delta;
                    end
                    rozmiarX = size(xSwietlikow, 2);
                    app.xEditField.Value = xSwietlikow(rozmiarX);
                    app.yEditField.Value = ySwietlikow(rozmiarX);
                    app.zEditField.Value = zSwietlikow(rozmiarX);
                catch
                    
                end
            end
        end

        % Value changed function: WzorDropDown
        function WzorDropDownValueChanged(app, event)
            value = app.WzorDropDown.Value;
            if value == "f(x, y) = x.*exp(-1.*(x.^2+y.^2))"
                app.ZakresEditField.Value = "-2 2 -2 2";
            else
                app.ZakresEditField.Value = "-5 5 -5 5";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FAUIFigure = uifigure('Visible', 'off');
            app.FAUIFigure.Name = 'FA';
            app.FAUIFigure.CloseRequestFcn = createCallbackFcn(app, @FAUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.FAUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label_2 = uilabel(app.FAUIFigure);
            app.Label_2.Text = '';
            app.Switch = uiswitch(app.FAUIFigure, 'slider');
            app.Switch.Items = {'Funkcja 3D', 'Świetliki'};
            app.Switch.Value = 'Funkcja 3D';
            app.Label = uilabel(app.FAUIFigure);
            app.Label.Text = 'Znalezienie maksimum lokalnego funkcji 3D za pomocą algorytmu świetlika (FA) ';
            app.LiczbaepokEditFieldLabel = uilabel(app.FAUIFigure);
            app.LiczbaepokEditFieldLabel.Text = 'Liczba epok';
            app.LiczbaepokEditField = uieditfield(app.FAUIFigure, 'numeric');
            app.LiczbaepokEditField.Limits = [1 500];
            app.LiczbaepokEditField.Tooltip = {'Zakres 1-500'};
            app.LiczbaepokEditField.Value = 100;
            app.LiczbawietlikwEditFieldLabel = uilabel(app.FAUIFigure);
            app.LiczbawietlikwEditFieldLabel.Text = 'Liczba świetlików';
            app.LiczbaSwietlikowEditField = uieditfield(app.FAUIFigure, 'numeric');
            app.LiczbaSwietlikowEditField.Limits = [1 50];
            app.LiczbaSwietlikowEditField.Tooltip = {'Zakres 1-50'};
            app.LiczbaSwietlikowEditField.Value = 12;
            app.AlfalosowoEditFieldLabel = uilabel(app.FAUIFigure);
            app.AlfalosowoEditFieldLabel.Text = 'Alfa - losowość';
            app.AlfaEditField = uieditfield(app.FAUIFigure, 'numeric');
            app.AlfaEditField.Limits = [0 1];
            app.AlfaEditField.ValueDisplayFormat = '%.1f';
            app.AlfaEditField.Tooltip = {'Zakres 0-1'};
            app.AlfaEditField.Value = 0.3;
            app.ZbirwktrymposzukiwanejestmaksimumEditFieldLabel = uilabel(app.FAUIFigure);
            app.ZbirwktrymposzukiwanejestmaksimumEditFieldLabel.Text = 'Zbiór, w którym poszukiwane jest maksimum';
            app.ZakresEditField = uieditfield(app.FAUIFigure, 'text');
            app.ZakresEditField.Editable = 'off';
            app.ZakresEditField.Tooltip = {'x1 x2 y1 y2'};
            app.ZakresEditField.Value = '-5 5 -5 5';
            app.ParametryLabel = uilabel(app.FAUIFigure);
            app.ParametryLabel.Text = 'Parametry';
            app.GammazdolnoabsorpcyjnaEditFieldLabel = uilabel(app.FAUIFigure);
            app.GammazdolnoabsorpcyjnaEditFieldLabel.Text = 'Gamma - zdolność absorpcyjna';
            app.GammaEditField = uieditfield(app.FAUIFigure, 'numeric');
            app.GammaEditField.Limits = [0.1 10];
            app.GammaEditField.ValueDisplayFormat = '%.1f';
            app.GammaEditField.Tooltip = {'Zakres 0.1-10.0'};
            app.GammaEditField.Value = 1;
            app.DeltaredukcjaalfaEditFieldLabel = uilabel(app.FAUIFigure);
            app.DeltaredukcjaalfaEditFieldLabel.Text = 'Delta - redukcja alfa';
            app.DeltaEditField = uieditfield(app.FAUIFigure, 'numeric');
            app.DeltaEditField.Limits = [0.9 0.99];
            app.DeltaEditField.ValueDisplayFormat = '%.2f';
            app.DeltaEditField.Tooltip = {'Zakres 0.9-0.99'};
            app.DeltaEditField.Value = 0.95;
            app.UruchomButton = uibutton(app.FAUIFigure, 'push');
            app.UruchomButton.ButtonPushedFcn = createCallbackFcn(app, @UruchomButtonPushed, true);
            app.UruchomButton.Text = 'Uruchom';
            app.xLabel = uilabel(app.FAUIFigure);
            app.xLabel.Text = 'x =';
            app.xEditField = uieditfield(app.FAUIFigure, 'numeric');
            app.xEditField.ValueDisplayFormat = '%.4f';
            app.xEditField.Editable = 'off';
            app.yLabel = uilabel(app.FAUIFigure);
            app.yLabel.Text = 'y =';
            app.yEditField = uieditfield(app.FAUIFigure, 'numeric');
            app.yEditField.ValueDisplayFormat = '%.4f';
            app.yEditField.Editable = 'off';
            app.zLabel = uilabel(app.FAUIFigure);
            app.zLabel.Text = 'z =';
            app.zEditField = uieditfield(app.FAUIFigure, 'numeric');
            app.zEditField.ValueDisplayFormat = '%.4f';
            app.zEditField.Editable = 'off';
            app.ZnalezionemaksimumlokalnefunkcjiLabel = uilabel(app.FAUIFigure);
            app.ZnalezionemaksimumlokalnefunkcjiLabel.Text = 'Znalezione maksimum lokalne funkcji';
            app.Wzrfunkcji2zmiennychLabel = uilabel(app.FAUIFigure);
            app.Wzrfunkcji2zmiennychLabel.Text = 'Wzór funkcji 2 zmiennych';
            app.WzorDropDown = uidropdown(app.FAUIFigure);
            app.WzorDropDown.Items = {'f(x, y) = sin(x/2) + cos(y/2)', 'f(x, y) = -sqrt(x.^2+y.^2)', 'f(x, y) = x.*exp(-1.*(x.^2+y.^2))', 'f(x, y) = 1-abs(x+y)-abs(y-x)'};
            app.WzorDropDown.ValueChangedFcn = createCallbackFcn(app, @WzorDropDownValueChanged, true);
            app.WzorDropDown.Value = 'f(x, y) = sin(x/2) + cos(y/2)';
            app.dlapooeniaxyLabel = uilabel(app.FAUIFigure);
            app.dlapooeniaxyLabel.Text = 'dla położenia (x, y),';
            app.iLabel = uilabel(app.FAUIFigure);
            app.iLabel.Text = 'i';
            app.gdzieLabel = uilabel(app.FAUIFigure);
            app.gdzieLabel.Text = 'gdzie';
            % Show the figure after all components are created
            app.FAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FAapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FAUIFigure)
        end
    end
end